classdef PlannerMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        LoginMenu             matlab.ui.container.Menu
        LogoutMenu            matlab.ui.container.Menu
        Menu                  matlab.ui.container.Menu
        NewMenu               matlab.ui.container.Menu
        OpenMenu              matlab.ui.container.Menu
        SaveMenu              matlab.ui.container.Menu
        CloseMenu             matlab.ui.container.Menu
        PlanMenu              matlab.ui.container.Menu
        ParametersMenu        matlab.ui.container.Menu
        UniqueTargetsMenu     matlab.ui.container.Menu
        ValidateMenu          matlab.ui.container.Menu
        SubmitMenu            matlab.ui.container.Menu
        ViewMenu              matlab.ui.container.Menu
        PlotGraphsPanel       matlab.ui.container.Panel
        PlotSkyMapPanel       matlab.ui.container.Panel
        ApprovedTargetsPanel  matlab.ui.container.Panel
        UITable               matlab.ui.control.Table
        PlanPanel             matlab.ui.container.Panel
        UniqueTargetsPanel    matlab.ui.container.Panel
        PlanParamsPanel       matlab.ui.container.Panel
        Panel                 matlab.ui.container.Panel
        UniqueButton          matlab.ui.control.Button
        GetApprovedButton     matlab.ui.control.Button
        ParamsButton          matlab.ui.control.Button
        ValidateButton        matlab.ui.control.Button
        SubmitButton          matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
        OpenButton            matlab.ui.control.Button
        NewButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        % 
        NewPlanApp   % Description
        OpenPlanApp %
        UniqueTargetsApp % 
        PlanParamsApp %
        ValidateApp %
        SubmitApp %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            %
            if isempty(app.NewPlanApp) || ~isvalid(app.NewPlanApp)
                app.NewPlanApp = ultrasat.planner.gui.NewPlan();
                app.NewPlanApp.MainApp = app;
                waitfor(app.NewPlanApp);
            end
            app.NewPlanApp.UIFigure.Visible = 'on';            
        end

        % Button pushed function: UniqueButton
        function UniqueButtonPushed(app, event)
            %

        end

        % Menu selected function: LoginMenu
        function LoginMenuSelected(app, event)
            % Login
        end

        % Menu selected function: LogoutMenu
        function LogoutMenuSelected(app, event)
            % Logout
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            %
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %
            %
            if isempty(app.UniqueTargetsApp) || ~isvalid(app.UniqueTargetsApp)
                app.UniqueTargetsApp = ultrasat.planner.gui.OpenPlan();
                app.UniqueTargetsApp.MainApp = app;
            end
            app.UniqueTargetsApp.UIFigure.Visible = 'on';                                    
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            %
            answer = questdlg('Are you sure you want to exit?', 'Confirm action', 'Yes', 'No', 'No');
            if strcmpi(answer, 'Yes')
                app.delete();
            else
                % Just do nothing
            end
        end

        % Menu selected function: UniqueTargetsMenu
        function UniqueTargetsMenuSelected(app, event)
            if isempty(app.UniqueTargetsApp) || ~isvalid(app.UniqueTargetsApp)
                app.UniqueTargetsApp = ultrasat.planner.gui.UniqueTargets();
                app.UniqueTargetsApp.MainApp = app;
            end
            app.UniqueTargetsApp.UIFigure.Visible = 'on';                                    
        end

        % Menu selected function: ParametersMenu
        function ParametersMenuSelected(app, event)
            %
            if isempty(app.PlanParamsApp) || ~isvalid(app.PlanParamsApp)
                app.PlanParamsApp = ultrasat.planner.gui.PlanParams();
                app.PlanParamsApp.MainApp = app;
            end
            app.PlanParamsApp.UIFigure.Visible = 'on';                                                
        end

        % Menu selected function: ValidateMenu
        function ValidateMenuSelected(app, event)
            %
        end

        % Menu selected function: SubmitMenu
        function SubmitMenuSelected(app, event)
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoginMenu = uimenu(app.FileMenu);
            app.LoginMenu.MenuSelectedFcn = createCallbackFcn(app, @LoginMenuSelected, true);
            app.LoginMenu.Text = 'Login';
            app.LogoutMenu = uimenu(app.FileMenu);
            app.LogoutMenu.MenuSelectedFcn = createCallbackFcn(app, @LogoutMenuSelected, true);
            app.LogoutMenu.Text = 'Logout';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = '---------';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.PlanMenu = uimenu(app.UIFigure);
            app.PlanMenu.Text = 'Plan';
            app.ParametersMenu = uimenu(app.PlanMenu);
            app.ParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersMenuSelected, true);
            app.ParametersMenu.Text = 'Parameters';
            app.UniqueTargetsMenu = uimenu(app.PlanMenu);
            app.UniqueTargetsMenu.MenuSelectedFcn = createCallbackFcn(app, @UniqueTargetsMenuSelected, true);
            app.UniqueTargetsMenu.Text = 'Unique Targets';
            app.ValidateMenu = uimenu(app.PlanMenu);
            app.ValidateMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidateMenuSelected, true);
            app.ValidateMenu.Text = 'Validate';
            app.SubmitMenu = uimenu(app.PlanMenu);
            app.SubmitMenu.MenuSelectedFcn = createCallbackFcn(app, @SubmitMenuSelected, true);
            app.SubmitMenu.Text = 'Submit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.Panel = uipanel(app.UIFigure);
            app.NewButton = uibutton(app.Panel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.Text = 'Save';
            app.SubmitButton = uibutton(app.Panel, 'push');
            app.SubmitButton.Text = 'Submit';
            app.ValidateButton = uibutton(app.Panel, 'push');
            app.ValidateButton.Text = 'Validate';
            app.ParamsButton = uibutton(app.Panel, 'push');
            app.ParamsButton.Text = 'Params';
            app.GetApprovedButton = uibutton(app.Panel, 'push');
            app.GetApprovedButton.Text = 'Get Approved';
            app.UniqueButton = uibutton(app.Panel, 'push');
            app.UniqueButton.ButtonPushedFcn = createCallbackFcn(app, @UniqueButtonPushed, true);
            app.UniqueButton.Text = 'Unique';
            app.PlanParamsPanel = uipanel(app.UIFigure);
            app.PlanParamsPanel.Title = 'Plan Params';
            app.UniqueTargetsPanel = uipanel(app.UIFigure);
            app.UniqueTargetsPanel.Title = 'Unique Targets';
            app.PlanPanel = uipanel(app.UIFigure);
            app.PlanPanel.Title = 'Plan';
            app.ApprovedTargetsPanel = uipanel(app.UIFigure);
            app.ApprovedTargetsPanel.Title = 'Approved Targets';
            app.UITable = uitable(app.ApprovedTargetsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PlotSkyMapPanel = uipanel(app.UIFigure);
            app.PlotSkyMapPanel.Title = 'Plot - Sky Map';
            app.PlotGraphsPanel = uipanel(app.UIFigure);
            app.PlotGraphsPanel.Title = 'Plot - Graphs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlannerMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end